classdef DiffractionDispersionSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SourceMenu                   matlab.ui.control.DropDown
        IncidentLightSourceLabel     matlab.ui.control.Label
        AngleBox                     matlab.ui.control.NumericEditField
        GlassMenu                    matlab.ui.control.DropDown
        OpticalGlassDropDownLabel    matlab.ui.control.Label
        AngleSlider                  matlab.ui.control.Slider
        StartingRayAngleSliderLabel  matlab.ui.control.Label
        ElementsMenu                 matlab.ui.control.DropDown
        OpticalElementsInterfacesDropDownLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TitleText                    matlab.ui.control.Label
        Axes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set axis equal
            axis(app.Axes, 'equal');
            % Initialize Axes to Air/Glass Surface
            medium2 = polyshape([0.5 0.5 1 1],[1 0 0 1]);
            plot(app.Axes, medium2,'FaceColor', 'cyan')
            title(app.Axes, 'Air/Glass Surface');
        end

        % Value changed function: ElementsMenu
        function ElementsMenuValueChanged(app, event)
            senario = app.ElementsMenu.Value;
            switch senario
                case 'Air/Glass Surface'
                    medium2 = polyshape([0.5 0.5 1 1],[1 0 0 1]);
                    plot(app.Axes, medium2,'FaceColor', 'cyan')
                    title(app.Axes, 'Air/Glass Surface');

                case 'Glass/Air Surface'
                    medium1 = polyshape([0 0 0.5 0.5], [1 0 0 1]);
                    plot(app.Axes, medium1, 'FaceColor', 'yellow');
                    title(app.Axes, 'Glass/Air Surface');

                case 'Plane Parallel Plate'
                    plate = polyshape([0.3 0.3 0.7 0.7], [0.8 0.2 0.2 0.8]);
                    plot(app.Axes, plate, 'FaceColor', 'green');
                    title(app.Axes, 'Plane Parallel Plate');

                case '60-Degree Prism'
                    prism = polyshape([0.4 0.6 0.5], [0.4134 0.4134 0.5866]);
                    plot(app.Axes, prism, 'FaceColor', 'blue');
                    title(app.Axes, '60-Degree Prism');

                case 'Simple Positive Lens'
                    % Parameters for the lens
                    a = 0.2; % Semi-major axis length
                    b = 1; % Semi-minor axis length
                    x_center = 0; % X-coordinate of the center
                    y_center = 0; % Y-coordinate of the center
                    
                    % Create an array of angles from 0 to 2*pi
                    theta = linspace(0, 2*pi, 100);
                    
                    % Parametric equations for the ellipse
                    x = a * cos(theta) + x_center;
                    y = b * sin(theta) + y_center;
                    plot(app.Axes,x, y);
                    fill(x, y, 'cyan', 'Parent', app.Axes); % 'b' is the color blue
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SourceMenu
        function SourceMenuValueChanged(app, event)
        source = app.SourceMenu.Value;
        switch source
            case 'white light'
                    lambda=[0.65 0.58 0.53 0.47]; % Wavelength [red yellow green blue]
                    n=refractive_index(lambda);
                    title(app.Axes, 'white light source');
            case 'red chromatic light'
                    lambda = 0.65;   % Red (650 nm)
                    n=refractive_index(lambda);
                    title(app.Axes, 'red chromatic light source');
            case 'yellow chromatic light'
                    lambda = 0.58; % Yellow (580 nm)
                    n=refractive_index(lambda);
                    title(app.Axes, 'red chromatic light source');  
            case 'green chromatic light'
                    lambda = 0.53;  % Green (530 nm)
                    n=refractive_index(lambda);
                    title(app.Axes, 'red chromatic light source');
            case 'blue chromatic light'
                    lambda = 0.47;   % Blue (470 nm)
                    n=refractive_index(lambda);
                    title(app.Axes, 'red chromatic light source');
        end
        end

        % Value changed function: AngleBox
        function AngleBoxValueChanged(app, event)
            angle = app.AngleBox.Value;
            app.AngleBox.Value = angle;
            app.AngleSlider.Value = angle;
            title(app.Axes,sprintf('Ray Angle: %.1f°', angle));
            
        end

        % Value changed function: AngleSlider
        function InputAngleValueChanged(app, event)
            angle = app.AngleSlider.Value;
            app.AngleBox.Value = angle; % Sync slider and box
            app.AngleSlider.Value = angle; 
            title(app.Axes, sprintf('Ray Angle: %.1f°', angle));
        end

        % Value changed function: GlassMenu
        function GlassMenuValueChanged(app, event)
            glass = app.GlassMenu.Value;
            switch glass
                case 'N-BK7'
                    % Define constants
                    B1 = 1.03961212;
                    C1 = 0.00600069867;
                    B2 = 0.231792344;
                    C2 = 0.0200179144;                    
                    B3 = 1.01046945;
                    C3 = 103.560653;   

                    % Define the function for refractive index n as a function of wavelength lambda
                    refractive_index = @(lambda) sqrt(1 + ...
                        (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                        (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                        (B3 .* lambda.^2) ./ (lambda.^2 - C3));

                    title(app.Axes, 'Glass: N-BK7');

                case 'N-SF11'
                    % Define constants
                    B1 = 1.73759695;
                    C1 = 0.013188707;
                    B2 = 0.313747346;
                    C2 = 0.0623068142;  
                    B3 = 1.89878101;
                    C3 = 155.23629;

                    % Define the function for refractive index n as a function of wavelength lambda
                    refractive_index = @(lambda) sqrt(1 + ...
                        (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                        (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                        (B3 .* lambda.^2) ./ (lambda.^2 - C3));

                    title(app.Axes, 'Glass: N-SF11');

                case 'N-F2'
                    % Define constants
                    B1 = 1.39757037;
                    C1 = 0.00995906143;                    
                    B2 = 0.159201403;
                    C2 = 0.0546931752;                    
                    B3 = 1.2686543;
                    C3 = 119.248346;
                    
                    % Define the function for refractive index n as a function of wavelength lambda
                    refractive_index = @(lambda) sqrt(1 + ...
                        (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                        (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                        (B3 .* lambda.^2) ./ (lambda.^2 - C3));

                    title(app.Axes, 'Glass: N-F_2');

                case 'F2'
                    % Define constants
                    B1 = 1.34533359;
                    C1 = 0.00997743871;  
                    B2 = 0.209073176;
                    C2 = 0.0470450767;
                    B3 = 0.937357162;
                    C3 = 111.886764;
                    
                    % Define the function for refractive index n as a function of wavelength lambda
                    refractive_index = @(lambda) sqrt(1 + ...
                        (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                        (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                        (B3 .* lambda.^2) ./ (lambda.^2 - C3));

                    title(app.Axes, 'Glass: F_2');

                case 'Calcium Fluoride'
                    % Define constants
                    a = 0.33973;
                    B1 = 0.69913;
                    C1 = 0.09374^2;
                    B2 = 0.11994;
                    C2 = 21.18^2;
                    B3 = 4.35181;
                    C3 = 38.46^2;
                    
                    % Define the function for refractive index n as a function of wavelength lambda
                    refractive_index = @(lambda) sqrt(1 + a + ...
                        (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                        (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                        (B3 .* lambda.^2) ./ (lambda.^2 - C3));

                    title(app.Axes, 'Glass: CaF_2');

                case 'Zinc Selenide'
                    % Define constants
                    a = 3.00;
                    B1 = 1.90;
                    C1 = 0.113;
                    
                    % Define the function for refractive index n as a function of wavelength lambda
                    refractive_index = @(lambda) sqrt(1 + a + ...
                        (B1 .* lambda.^2) ./ (lambda.^2 - C1));

                    title(app.Axes, 'Glass: ZnSe');

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes = uiaxes(app.UIFigure);
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Diffraction/Dispersion Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OpticalElementsInterfacesDropDownLabel = uilabel(app.UIFigure);
            app.OpticalElementsInterfacesDropDownLabel.Text = 'Optical Elements/Interfaces';
            app.ElementsMenu = uidropdown(app.UIFigure);
            app.ElementsMenu.Items = {'Air/Glass Surface', 'Glass/Air Surface', 'Plane Parallel Plate', '60-Degree Prism', 'Simple Positive Lens'};
            app.ElementsMenu.ValueChangedFcn = createCallbackFcn(app, @ElementsMenuValueChanged, true);
            app.ElementsMenu.Value = 'Air/Glass Surface';
            app.StartingRayAngleSliderLabel = uilabel(app.UIFigure);
            app.StartingRayAngleSliderLabel.Text = 'Starting Ray Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [0 90];
            app.AngleSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.AngleSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @InputAngleValueChanged, true);
            app.AngleSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90];
            app.OpticalGlassDropDownLabel = uilabel(app.UIFigure);
            app.OpticalGlassDropDownLabel.Text = 'Optical Glass';
            app.GlassMenu = uidropdown(app.UIFigure);
            app.GlassMenu.Items = {'N-BK7', 'N-SF11', 'N-F2', 'F2', 'Calcium Fluoride', 'Zinc Selenide'};
            app.GlassMenu.ValueChangedFcn = createCallbackFcn(app, @GlassMenuValueChanged, true);
            app.GlassMenu.Value = 'N-BK7';
            app.AngleBox = uieditfield(app.UIFigure, 'numeric');
            app.AngleBox.ValueChangedFcn = createCallbackFcn(app, @AngleBoxValueChanged, true);
            app.AngleBox.Value = 30;
            app.IncidentLightSourceLabel = uilabel(app.UIFigure);
            app.IncidentLightSourceLabel.Text = 'Incident Light Source';
            app.SourceMenu = uidropdown(app.UIFigure);
            app.SourceMenu.Items = {'white light', 'red chromatic light', 'yellow chromatic light', 'green chromatic light', 'blue chromatic light'};
            app.SourceMenu.ValueChangedFcn = createCallbackFcn(app, @SourceMenuValueChanged, true);
            app.SourceMenu.Value = 'white light';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffractionDispersionSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end