classdef DiffractionDispersionSimulator_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        IncidentLightSourceLabel     matlab.ui.control.Label
        SourceMenu                   matlab.ui.control.DropDown
        AngleBox                     matlab.ui.control.NumericEditField
        GlassMenu                    matlab.ui.control.DropDown
        OpticalGlassDropDownLabel    matlab.ui.control.Label
        AngleSlider                  matlab.ui.control.Slider
        StartingRayAngleSliderLabel  matlab.ui.control.Label
        ElementsMenu                 matlab.ui.control.DropDown
        OpticalElementsInterfacesDropDownLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TitleText                    matlab.ui.control.Label
        Axes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set axis equal
            axis(app.Axes, 'equal');
            xlim(app.Axes, [0 2]);
            ylim(app.Axes, [0 1]);

            % Initialize Axes to Air/Glass Surface
            medium2 = polyshape([1 1 2 2],[1 0 0 1]);
            plot(app.Axes, medium2,'FaceColor', 'cyan')

            % Initialize refractive/dispersion coefficients (BK7)
            global a B1 C1 B2 C2 B3 C3
            a = 0;
            B1 = 1.03961212;    C1 = 0.00600069867;
            B2 = 0.231792344;   C2 = 0.0200179144;
            B3 = 1.01046945;    C3 = 103.560653;
        end

        % Value changed function: ElementsMenu
        function ElementsMenuValueChanged(app, event)
            cla(app.Axes)

            interface = app.ElementsMenu.Value;
            switch interface
                case 'Air/Glass Surface'
                    medium2 = polyshape([1 1 2 2],[1 0 0 1]);
                    plot(app.Axes, medium2,'FaceColor', 'cyan')

                case 'Glass/Air Surface'
                    medium1 = polyshape([0 0 1 1], [1 0 0 1]);
                    plot(app.Axes, medium1, 'FaceColor', 'cyan');

                case 'Plane Parallel Plate'
                    plate = polyshape([0.8 0.8 1.2 1.2], [0 1 1 0]);
                    plot(app.Axes, plate, 'FaceColor', 'green');

                case '60-Degree Prism'
                    prism = polyshape([1-1/sqrt(3) 1+1/sqrt(3) 1], [0 0 1]);
                    plot(app.Axes, prism, 'FaceColor', 'blue');
                    
                case 'Simple Positive Lens'
                    % Parameters for the biconvex lens
                    r1 = 2;  % Radius of curvature for the first side
                    r2 = 2;  % Radius of curvature for the second side
                    thickness = 0.2; % Thickness of the lens
                    height = 1; % Height of the lens
                    x_center = 1;
                    y_center = 0.5;
                    
                    % Create the x-coordinates for the lens
                    y1 = linspace(-height, height, 100);
                    y2 = linspace(-height, height, 100);
                    
                    % Calculate the y-coordinates for both sides of the lens
                    x1 = sqrt(r1^2 - y1.^2) - (r1 - thickness) + x_center;
                    x2 = -sqrt(r2^2 - y2.^2) + (r2 - thickness) + x_center;

                    y1 = y1 + y_center;
                    y2 = y2 + y_center;
                    
                    % Plot the lens
                    hold(app.Axes, "on")
                    plot(app.Axes, x1, y1, 'b');
                    plot(app.Axes, x2, y2, 'b');
                    fill(app.Axes, [x1, fliplr(x2)], [y1, fliplr(y2)], 'cyan', 'EdgeColor', 'b');
                    hold(app.Axes, "off")
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SourceMenu
        function SourceMenuValueChanged(app, event)

        end

        % Value changed function: AngleBox
        function AngleBoxValueChanged(app, event)
        angle=app.AngleBox.Value;
        app.AngleSlider.Value=angle;
        % angle_r=deg2rad(angle);
        % x=linspace(0,1,1000);
        % y_in=tan(angle_r).*x+1;
        % 
        %     if strcmp(app.ElementsMenu.Value, 'Air/Glass Surface')
        %         theta2=asin((1/n_y).*sin(angle_r));
        %         y_out=tan(theta2).*x;
        %         plot(x,y_in)
        %         plot(x,y_out)
        %         plot(app.UIAxes, x, y_in, 'r', x, y_out, 'b'); % Replace 'UIAxes' with your app's axes component
        %         legend(app.UIAxes, 'Incoming Ray', 'Outgoing Ray');
        %         xlabel(app.UIAxes, 'X');
        %         ylabel(app.UIAxes, 'Y');
        %         title(app.UIAxes, 'Ray Trajectories');
        %     else
        %         disp('The selected surface is not Air/Glass Surface');
        % 
        %     end
        end

        % Value changed function: AngleSlider
        function InputAngleValueChanged(app, event)
            angle = app.AngleSlider.Value;
            app.AngleBox.Value = angle; % Sync slider and box
        end

        % Value changed function: GlassMenu
        function GlassMenuValueChanged(app, event)
            glass = app.GlassMenu.Value;
            % Make variables global for calculation
            global a B1 C1 B2 C2 B3 C3
            switch glass
                case 'N-BK7'
                    a = 0;
                    B1 = 1.03961212;    C1 = 0.00600069867;
                    B2 = 0.231792344;   C2 = 0.0200179144;                    
                    B3 = 1.01046945;    C3 = 103.560653;   

                case 'N-SF11'
                    a = 0;
                    B1 = 1.73759695;    C1 = 0.013188707;
                    B2 = 0.313747346;   C2 = 0.0623068142;  
                    B3 = 1.89878101;    C3 = 155.23629;

                case 'N-F2'
                    a = 0;
                    B1 = 1.39757037;    C1 = 0.00995906143;                    
                    B2 = 0.159201403;   C2 = 0.0546931752;                    
                    B3 = 1.2686543;     C3 = 119.248346;

                case 'F2'
                    a = 0;
                    B1 = 1.34533359;    C1 = 0.00997743871;  
                    B2 = 0.209073176;   C2 = 0.0470450767;
                    B3 = 0.937357162;   C3 = 111.886764;

                case 'Calcium Fluoride'
                    a = 0;
                    B1 = 0.56759;       C1 = 0.050264^2;
                    B2 = 0.47134;       C2 = 0.10026^2;
                    B3 = 3.8485;       C3 = 34.649^2;
                    
                case 'Zinc Selenide'
                    a = 3.00;
                    B1 = 1.90;          C1 = 0.113;
                    B2 = 0; C2 = 1;
                    B3 = 0; C3 = 1;
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Initialization
            % Clear original plot
            cla(app.Axes);
            theta = deg2rad(app.AngleBox.Value);

            %% Plot background
            hold(app.Axes,"on")
            interface = app.ElementsMenu.Value;
            switch interface
                case 'Air/Glass Surface'
                    medium2 = polyshape([1 1 2 2],[1 0 0 1]);
                    plot(app.Axes, medium2,'FaceColor', 'cyan')

                case 'Glass/Air Surface'
                    medium1 = polyshape([0 0 1 1], [1 0 0 1]);
                    plot(app.Axes, medium1, 'FaceColor', 'cyan');

                case 'Plane Parallel Plate'
                    plate = polyshape([0.8 0.8 1.2 1.2], [0 1 1 0]);
                    plot(app.Axes, plate, 'FaceColor', 'green');

                case '60-Degree Prism'
                    prism = polyshape([1-1/sqrt(3) 1+1/sqrt(3) 1], [0 0 1]);
                    plot(app.Axes, prism, 'FaceColor', 'blue');

                case 'Simple Positive Lens'
                    % Parameters for the biconvex lens
                    r1 = 2;  % Radius of curvature for the first side
                    r2 = 2;  % Radius of curvature for the second side
                    thickness = 0.2; % Thickness of the lens
                    height = 1; % Height of the lens
                    x_center = 1;
                    y_center = 0.5;
                    
                    % Create the x-coordinates for the lens
                    y1 = linspace(-height, height, 100);
                    y2 = linspace(-height, height, 100);
                    
                    % Calculate the y-coordinates for both sides of the lens
                    x1 = sqrt(r1^2 - y1.^2) - (r1 - thickness) + x_center;
                    x2 = -sqrt(r2^2 - y2.^2) + (r2 - thickness) + x_center;

                    y1 = y1 + y_center;
                    y2 = y2 + y_center;
                    
                    % Plot the lens
                    hold(app.Axes, "on")
                    plot(app.Axes, x1, y1, 'b');
                    plot(app.Axes, x2, y2, 'b');
                    fill(app.Axes, [x1, fliplr(x2)], [y1, fliplr(y2)], 'cyan', 'EdgeColor', 'b');
                    hold(app.Axes, "off")
            end

            %% Calculate dispersion (refractive index vs. wavelength)
            global a B1 C1 B2 C2 B3 C3
            refractive_index = @(lambda) sqrt(1 + a + ...
                (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                (B3 .* lambda.^2) ./ (lambda.^2 - C3));

            source = app.SourceMenu.Value;
            switch source
                case 'white light'
                    n = 1.33;
                    color = 'black';
                case 'red chromatic light'
                    n = refractive_index(0.65);
                    color = 'red';
                case 'yellow chromatic light'
                    n = refractive_index(0.58);
                    color = '#996600';
                case 'green chromatic light'
                    n = refractive_index(0.53);
                    color = '#006400';
                case 'blue chromatic light'
                    n = refractive_index(0.47);
                    color = 'blue';
            end

            %% Plot
            interface = app.ElementsMenu.Value;
            if strcmp(source,'white light') == 1
                % Non-monochromatic Light
                colorList = ["red" "#996600" "yellow" "green" "cyan" "blue" "magenta"];
                lam = [0.65 0.61 0.58 0.53 0.49 0.47 0.4];
                index = refractive_index(lam);
                switch interface
                    case 'Air/Glass Surface'
                        for i = 1:7
                            n = index(i);
                            color = colorList(i);
                            % Left Half
                                x = linspace(0,1,100);
                                m = tan(theta); % Slope
                                b = 0.5; % Center position
                                y = -m.*x + b; % Pass (1,0.5)
                                y = flip(y); % Plot from 1 to 0
                                plot(app.Axes, x, y,'Color','black');
                            % Right Half
                                theta2 = asin(1 ./ n .* sin(theta));
                                m2 = tan(theta2);
                                y2 = m2.*x + b; % Pass (1,0.5)
                                x = x + 1;
                                plot(app.Axes, x, y2,'Color',color);
                            % Axis
                                x_axis = linspace(0,2,10);
                                plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');
                        end
                    case 'Glass/Air Surface'
    
                    case 'Plane Parallel Plate'
    
                    case '60-Degree Prism'
    
                    case 'Simple Positive Lens'
                end
            else
                switch interface
                    % Monochromatic Light
                    case 'Air/Glass Surface'
                        % Left Half
                            x = linspace(0,1,100);
                            m = tan(theta); % Slope
                            b = 0.5; % Center position
                            y = -m.*x + b; % Pass (1,0.5)
                            y = flip(y); % Plot from 1 to 0
                            plot(app.Axes, x, y, "LineWidth", 1.5,'Color',color);
                        % Right Half
                            theta2 = asin(1 ./ n .* sin(theta));
                            m2 = tan(theta2);
                            y2 = m2.*x + b; % Pass (1,0.5)
                            x = x + 1;
                            plot(app.Axes, x, y2, "LineWidth", 1.5,'Color',color);
                        % Axis
                            x_axis = linspace(0,2,10);
                            plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');
                    case 'Glass/Air Surface'
                        % Left Half
                            x = linspace(0,1,100);
                            m = tan(theta); % Slope
                            b = 0.5; % Center position
                            y = -m.*x + b; % Pass (1,0.5)
                            y = flip(y); % Plot from 1 to 0
                            plot(app.Axes, x, y, "LineWidth", 1.5,'Color',color);
                        % Right Half
                            theta2 = asin(n ./ 1 .* sin(theta));
                            m2 = tan(theta2);
                            y2 = m2.*x + b; % Pass (1,0.5)
                            x = x + 1;
                            plot(app.Axes, x, y2, "LineWidth", 1.5,'Color',color);
                        % Axis
                            x_axis = linspace(0,2,10);
                            plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');
                    case 'Plane Parallel Plate'
    
                    case '60-Degree Prism'

% Incident Ray
x_incident = linspace(0, 1, 100);
m_incident = tan(theta); % Slope of incident ray
b_incident = 0.5; % Intersection y-coordinate at x = 1
y_incident = -m_incident .* x_incident + b_incident; % Define incident ray
plot(app.Axes, x_incident, y_incident, "LineWidth", 1.5, 'Color', color);

% Refracted Ray (Inside Prism)
n_air = 1.0; % Refractive index of air
n_prism = n; % Refractive index of prism

% Calculate refracted angle at first interface using Snell's Law
theta_refracted = asin((n_air / n_prism) * sin(theta));
m_refracted = tan(theta_refracted); % Slope of refracted ray

% Geometry of prism: refracted ray ends at x = 1 + 1/sqrt(3)
x_refracted = linspace(1, 1 + 1/sqrt(3), 100);
y_refracted = m_refracted .* (x_refracted - 1) + b_incident; % Adjust for starting point
plot(app.Axes, x_refracted, y_refracted, "LineWidth", 1.5, 'Color', color);

% Emergent Ray (Second Interface)
% Find the exit point of the refracted ray
x_exit = 1 + 1/sqrt(3);
y_exit = m_refracted * (x_exit - 1) + b_incident;

% Calculate emergent angle using Snell's Law at second interface
theta_emergent = asin((n_prism / n_air) * sin(theta_refracted));
m_emergent = tan(theta_emergent); % Slope of emergent ray

% Define the emergent ray
x_emergent = linspace(x_exit, x_exit + 1, 100); % Starts at exit point
y_emergent = m_emergent .* (x_emergent - x_exit) + y_exit; % Adjust for starting point
plot(app.Axes, x_emergent, y_emergent, "LineWidth", 1.5, 'Color', color);

% Draw Axis for reference
x_axis = linspace(0, x_exit + 1, 100);
plot(app.Axes, x_axis, 0 .* x_axis, "--", 'Color', 'black');

% Add Labels and Legend
xlabel(app.Axes, 'X-axis');
ylabel(app.Axes, 'Y-axis');
title(app.Axes, '60-Degree Prism with Incident, Refracted, and Emergent Rays');


                    case 'Simple Positive Lens'

                end
            end

            hold(app.Axes,"off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Axes = uiaxes(app.UIFigure);
            app.Axes.CLim = [0 1];
            app.Axes.ALim = [0 1];
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Diffraction/Dispersion Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OpticalElementsInterfacesDropDownLabel = uilabel(app.UIFigure);
            app.OpticalElementsInterfacesDropDownLabel.Text = 'Optical Elements/Interfaces';
            app.ElementsMenu = uidropdown(app.UIFigure);
            app.ElementsMenu.Items = {'Air/Glass Surface', 'Glass/Air Surface', 'Plane Parallel Plate', '60-Degree Prism', 'Simple Positive Lens'};
            app.ElementsMenu.ValueChangedFcn = createCallbackFcn(app, @ElementsMenuValueChanged, true);
            app.ElementsMenu.Value = 'Air/Glass Surface';
            app.StartingRayAngleSliderLabel = uilabel(app.UIFigure);
            app.StartingRayAngleSliderLabel.Text = 'Starting Ray Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [-90 90];
            app.AngleSlider.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.AngleSlider.MajorTickLabels = {'-90', '-80', '-70', '-60', '-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @InputAngleValueChanged, true);
            app.AngleSlider.MinorTicks = [-90 -88 -86 -84 -82 -80 -78 -76 -74 -72 -70 -68 -66 -64 -62 -60 -58 -56 -54 -52 -50 -48 -46 -44 -42 -40 -38 -36 -34 -32 -30 -28 -26 -24 -22 -20 -18 -16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90];
            app.OpticalGlassDropDownLabel = uilabel(app.UIFigure);
            app.OpticalGlassDropDownLabel.Text = 'Optical Glass';
            app.GlassMenu = uidropdown(app.UIFigure);
            app.GlassMenu.Items = {'N-BK7', 'N-SF11', 'N-F2', 'F2', 'Calcium Fluoride', 'Zinc Selenide'};
            app.GlassMenu.ValueChangedFcn = createCallbackFcn(app, @GlassMenuValueChanged, true);
            app.GlassMenu.Value = 'N-BK7';
            app.AngleBox = uieditfield(app.UIFigure, 'numeric');
            app.AngleBox.ValueChangedFcn = createCallbackFcn(app, @AngleBoxValueChanged, true);
            app.SourceMenu = uidropdown(app.UIFigure);
            app.SourceMenu.Items = {'white light', 'red chromatic light', 'yellow chromatic light', 'green chromatic light', 'blue chromatic light'};
            app.SourceMenu.ValueChangedFcn = createCallbackFcn(app, @SourceMenuValueChanged, true);
            app.SourceMenu.Value = 'white light';
            app.IncidentLightSourceLabel = uilabel(app.UIFigure);
            app.IncidentLightSourceLabel.Text = 'Incident Light Source';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffractionDispersionSimulator_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end