classdef DiffractionDispersionSimulator_3copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        IncidentLightSourceLabel     matlab.ui.control.Label
        SourceMenu                   matlab.ui.control.DropDown
        AngleBox                     matlab.ui.control.NumericEditField
        GlassMenu                    matlab.ui.control.DropDown
        OpticalGlassDropDownLabel    matlab.ui.control.Label
        AngleSlider                  matlab.ui.control.Slider
        StartingRayAngleSliderLabel  matlab.ui.control.Label
        ElementsMenu                 matlab.ui.control.DropDown
        OpticalElementsInterfacesDropDownLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TitleText                    matlab.ui.control.Label
        Axes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set axis equal
            axis(app.Axes, 'equal');
            xlim(app.Axes, [0 2]);
            ylim(app.Axes, [0 1]);

            % Initialize Axes to Air/Glass Surface
            medium2 = polyshape([1 1 2 2],[1 0 0 1]);
            plot(app.Axes, medium2,'FaceColor', 'cyan')

            % Initialize refractive/dispersion coefficients (BK7)
            global a B1 C1 B2 C2 B3 C3
            a = 0;
            B1 = 1.03961212;    C1 = 0.00600069867;
            B2 = 0.231792344;   C2 = 0.0200179144;                    
            B3 = 1.01046945;    C3 = 103.560653;   


        end

        % Value changed function: ElementsMenu
        function ElementsMenuValueChanged(app, event)
            cla(app.Axes)

            senario = app.ElementsMenu.Value;
            switch senario
                case 'Air/Glass Surface'
                    medium2 = polyshape([1 1 2 2],[1 0 0 1]);
                    plot(app.Axes, medium2,'FaceColor', 'cyan')

                case 'Glass/Air Surface'
                    medium1 = polyshape([0 0 1 1], [1 0 0 1]);
                    plot(app.Axes, medium1, 'FaceColor', 'cyan');

                case 'Plane Parallel Plate'
                    plate = polyshape([0.8 0.8 1.2 1.2], [0 1 1 0]);
                    plot(app.Axes, plate, 'FaceColor', 'green');

                case '60-Degree Prism'
                    prism = polyshape([1-1/sqrt(3) 1+1/sqrt(3) 1], [0 0 1]);
                    plot(app.Axes, prism, 'FaceColor', 'blue');

                case 'Simple Positive Lens'
                    % Parameters for the biconvex lens
                    r1 = 2;  % Radius of curvature for the first side
                    r2 = 2;  % Radius of curvature for the second side
                    thickness = 0.2; % Thickness of the lens
                    height = 1; % Height of the lens
                    x_center = 1;
                    y_center = 0.5;
                    
                    % Create the x-coordinates for the lens
                    y1 = linspace(-height, height, 100);
                    y2 = linspace(-height, height, 100);
                    
                    % Calculate the y-coordinates for both sides of the lens
                    x1 = sqrt(r1^2 - y1.^2) - (r1 - thickness) + x_center;
                    x2 = -sqrt(r2^2 - y2.^2) + (r2 - thickness) + x_center;

                    y1 = y1 + y_center;
                    y2 = y2 + y_center;
                    
                    % Plot the lens
                    hold(app.Axes, "on")
                    plot(app.Axes, x1, y1, 'b');
                    plot(app.Axes, x2, y2, 'b');
                    fill(app.Axes, [x1, fliplr(x2)], [y1, fliplr(y2)], 'cyan', 'EdgeColor', 'b');
                    hold(app.Axes, "off")
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SourceMenu
        function SourceMenuValueChanged(app, event)
            global a B1 C1 B2 C2 B3 C3
            refractive_index = @(lambda) sqrt(1 + a + ...
                (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                (B3 .* lambda.^2) ./ (lambda.^2 - C3));
            source = app.SourceMenu.Value;
            cla(app.Axes); 

            switch source
                case 'white light'
                        lambda = linspace(0.4,0.7,100);
                        n = refractive_index(lambda);
                case 'red chromatic light'
                        lambda = 0.65;   % Red (650 nm)
                        n_r = refractive_index(lambda);
                case 'yellow chromatic light'
                        lambda = 0.58; % Yellow (580 nm)
                        n_y = refractive_index(lambda);
                case 'green chromatic light'
                        lambda = 0.53;  % Green (530 nm)
                        n_g = refractive_index(lambda);
                case 'blue chromatic light'
                        lambda = 0.47;   % Blue (470 nm)
                        n_b = refractive_index(lambda);
            end


        end

        % Value changed function: AngleBox
        function AngleBoxValueChanged(app, event)
        angle=app.AngleBox.Value;
        app.AngleSlider.Value=angle;
        % angle_r=deg2rad(angle);
        % x=linspace(0,1,1000);
        % y_in=tan(angle_r).*x+1;
        % 
        %     if strcmp(app.ElementsMenu.Value, 'Air/Glass Surface')
        %         theta2=asin((1/n_y).*sin(angle_r));
        %         y_out=tan(theta2).*x;
        %         plot(x,y_in)
        %         plot(x,y_out)
        %         plot(app.UIAxes, x, y_in, 'r', x, y_out, 'b'); % Replace 'UIAxes' with your app's axes component
        %         legend(app.UIAxes, 'Incoming Ray', 'Outgoing Ray');
        %         xlabel(app.UIAxes, 'X');
        %         ylabel(app.UIAxes, 'Y');
        %         title(app.UIAxes, 'Ray Trajectories');
        %     else
        %         disp('The selected surface is not Air/Glass Surface');
        % 
        %     end
        end

        % Value changed function: AngleSlider
        function InputAngleValueChanged(app, event)
            angle = app.AngleSlider.Value;
            app.AngleBox.Value = angle; % Sync slider and box
        end

        % Value changed function: GlassMenu
        function GlassMenuValueChanged(app, event)
            glass = app.GlassMenu.Value;
            % Make variables global for calculation
            global a B1 C1 B2 C2 B3 C3
            switch glass
                case 'N-BK7'
                    a = 0;
                    B1 = 1.03961212;    C1 = 0.00600069867;
                    B2 = 0.231792344;   C2 = 0.0200179144;                    
                    B3 = 1.01046945;    C3 = 103.560653;   
                    
                case 'N-SF11'
                    a = 0;
                    B1 = 1.73759695;    C1 = 0.013188707;
                    B2 = 0.313747346;   C2 = 0.0623068142;  
                    B3 = 1.89878101;    C3 = 155.23629;
                    
                case 'N-F2'
                    a = 0;
                    B1 = 1.39757037;    C1 = 0.00995906143;                    
                    B2 = 0.159201403;   C2 = 0.0546931752;                    
                    B3 = 1.2686543;     C3 = 119.248346;

                case 'F2'
                    a = 0;
                    B1 = 1.34533359;    C1 = 0.00997743871;  
                    B2 = 0.209073176;   C2 = 0.0470450767;
                    B3 = 0.937357162;   C3 = 111.886764;

                case 'Calcium Fluoride'
                    a = 0.33973;
                    B1 = 0.69913;       C1 = 0.09374^2;
                    B2 = 0.11994;       C2 = 21.18^2;
                    B3 = 4.35181;       C3 = 38.46^2;
                    
                case 'Zinc Selenide'
                    a = 3.00;
                    B1 = 1.90;          C1 = 0.113;
                    B2 = 0; C2 = 1;
                    B3 = 0; C3 = 1;
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Axes = uiaxes(app.UIFigure);
            app.Axes.CLim = [0 1];
            app.Axes.ALim = [0 1];
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Diffraction/Dispersion Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OpticalElementsInterfacesDropDownLabel = uilabel(app.UIFigure);
            app.OpticalElementsInterfacesDropDownLabel.Text = 'Optical Elements/Interfaces';
            app.ElementsMenu = uidropdown(app.UIFigure);
            app.ElementsMenu.Items = {'Air/Glass Surface', 'Glass/Air Surface', 'Plane Parallel Plate', '60-Degree Prism', 'Simple Positive Lens'};
            app.ElementsMenu.ValueChangedFcn = createCallbackFcn(app, @ElementsMenuValueChanged, true);
            app.ElementsMenu.Value = 'Air/Glass Surface';
            app.StartingRayAngleSliderLabel = uilabel(app.UIFigure);
            app.StartingRayAngleSliderLabel.Text = 'Starting Ray Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [-90 90];
            app.AngleSlider.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.AngleSlider.MajorTickLabels = {'-90', '-80', '-70', '-60', '-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @InputAngleValueChanged, true);
            app.AngleSlider.MinorTicks = [-90 -88 -86 -84 -82 -80 -78 -76 -74 -72 -70 -68 -66 -64 -62 -60 -58 -56 -54 -52 -50 -48 -46 -44 -42 -40 -38 -36 -34 -32 -30 -28 -26 -24 -22 -20 -18 -16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90];
            app.OpticalGlassDropDownLabel = uilabel(app.UIFigure);
            app.OpticalGlassDropDownLabel.Text = 'Optical Glass';
            app.GlassMenu = uidropdown(app.UIFigure);
            app.GlassMenu.Items = {'N-BK7', 'N-SF11', 'N-F2', 'F2', 'Calcium Fluoride', 'Zinc Selenide'};
            app.GlassMenu.ValueChangedFcn = createCallbackFcn(app, @GlassMenuValueChanged, true);
            app.GlassMenu.Value = 'N-BK7';
            app.AngleBox = uieditfield(app.UIFigure, 'numeric');
            app.AngleBox.ValueChangedFcn = createCallbackFcn(app, @AngleBoxValueChanged, true);
            app.AngleBox.Value = 90;
            app.SourceMenu = uidropdown(app.UIFigure);
            app.SourceMenu.Items = {'white light', 'red chromatic light', 'yellow chromatic light', 'green chromatic light', 'blue chromatic light'};
            app.SourceMenu.ValueChangedFcn = createCallbackFcn(app, @SourceMenuValueChanged, true);
            app.SourceMenu.Value = 'white light';
            app.IncidentLightSourceLabel = uilabel(app.UIFigure);
            app.IncidentLightSourceLabel.Text = 'Incident Light Source';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffractionDispersionSimulator_3copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end