classdef DiffractionDispersionSimulator_finalversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CriticalAngleWarningEditField  matlab.ui.control.EditField
        CriticalAngleWarningEditFieldLabel  matlab.ui.control.Label
        IncidentLightSourceLabel       matlab.ui.control.Label
        SourceMenu                     matlab.ui.control.DropDown
        AngleBox                       matlab.ui.control.NumericEditField
        GlassMenu                      matlab.ui.control.DropDown
        OpticalGlassDropDownLabel      matlab.ui.control.Label
        AngleSlider                    matlab.ui.control.Slider
        StartingRayAngleSliderLabel    matlab.ui.control.Label
        ElementsMenu                   matlab.ui.control.DropDown
        OpticalElementsInterfacesDropDownLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TitleText                      matlab.ui.control.Label
        Axes                           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function plotter(app)
            %% Initialization
            global index
            % Clear original plot
            cla(app.Axes);
            set(app.Axes, 'Color', [0.7, 0.7, 0.7]);
            % set(app.Axes, 'Color', [0 0 0]);
            theta = deg2rad(app.AngleBox.Value);

            %% Plot background
            hold(app.Axes,"on")
            interface = app.ElementsMenu.Value;
            app.CriticalAngleWarningEditField.Value = "have not reach yet";
            switch interface
                case 'Air/Glass Surface'
                    medium2 = polyshape([1 1 2 2],[1 0 0 1]);
                    plot(app.Axes, medium2,'FaceColor', 'cyan', 'FaceAlpha', 0.8)

                case 'Glass/Air Surface'
                    medium1 = polyshape([0 0 1 1], [1 0 0 1]);
                    plot(app.Axes, medium1, 'FaceColor', 'cyan', 'FaceAlpha', 0.8);

                case 'Plane Parallel Plate'
                    plate = polyshape([0.8 0.8 1.2 1.2], [0 1 1 0]);
                    plot(app.Axes, plate, 'FaceColor', 'cyan', 'FaceAlpha', 0.8);

                case '60-Degree Prism'
                    prism = polyshape([1-1/sqrt(3) 1+1/sqrt(3) 1], [0 0 1]);
                    plot(app.Axes, prism, 'FaceColor', 'cyan', 'FaceAlpha', 0.8);

                case 'Simple Positive Lens'
                    % Parameters for the biconvex lens
                    r1 = 2;  % Radius of curvature for the first side
                    r2 = 2;  % Radius of curvature for the second side
                    thickness = 0.2; % Thickness of the lens
                    height = 1; % Height of the lens
                    x_center = 1;
                    y_center = 0.5;
                    
                    % Create the x-coordinates for the lens
                    y1 = linspace(-height, height, 100);
                    y2 = linspace(-height, height, 100);
                    
                    % Calculate the y-coordinates for both sides of the lens
                    x1 = sqrt(r1^2 - y1.^2) - (r1 - thickness) + x_center;
                    x2 = -sqrt(r2^2 - y2.^2) + (r2 - thickness) + x_center;

                    y1 = y1 + y_center;
                    y2 = y2 + y_center;
                    
                    % Plot the lens
                    hold(app.Axes, "on")
                    plot(app.Axes, x1, y1, 'b');
                    plot(app.Axes, x2, y2, 'b');
                    fill(app.Axes, [x1, fliplr(x2)], [y1, fliplr(y2)], 'cyan', 'EdgeColor', 'b', 'FaceAlpha', 0.8);
                    
            end

            %% Calculate dispersion (refractive index vs. wavelength)
            global a B1 C1 B2 C2 B3 C3
            global wavelength
            refractive_index = @(lambda) sqrt(1 + a + ...
                (B1 .* lambda.^2) ./ (lambda.^2 - C1) + ...
                (B2 .* lambda.^2) ./ (lambda.^2 - C2) + ...
                (B3 .* lambda.^2) ./ (lambda.^2 - C3));

            source = app.SourceMenu.Value;
            switch source
                case 'white light'
                    n = 1.33;
                    color = 'white';
                case 'red chromatic light'
                    wavelength = 0.65;
                    n = refractive_index(wavelength);
                    color = 'red';
                case 'yellow chromatic light'
                    wavelength = 0.58;
                    n = refractive_index(wavelength);
                    color = 'yellow';
                case 'green chromatic light'
                    wavelength = 0.53;
                    n = refractive_index(wavelength);
                    color = 'green';
                case 'blue chromatic light'
                    wavelength = 0.47;
                    n = refractive_index(wavelength);
                    color = 'blue';
            end
            %% Plot
            interface = app.ElementsMenu.Value;
            if strcmp(source,'white light') == 1
                % Non-monochromatic Light
                colorList = ["red" "#996600" "yellow" "green" "cyan" "blue" "magenta"]; % Define color names
                lam = [0.65 0.61 0.58 0.53 0.49 0.47 0.4]; % Define wavelengths for the colors
                index = refractive_index(lam); % Define refractive indices
                switch interface
                    case 'Air/Glass Surface'
                        for i = 1:7
                            n = index(i);
                            color = colorList(i);
                            % Left Half
                                x = linspace(0,1,100);
                                m = tan(theta); % Slope
                                b = 0.5; % Center position
                                y = -m.*x + b; % Pass (1,0.5)
                                y = flip(y); % Plot from 1 to 0
                                plot(app.Axes, x, y,'Color','white','linewidth',1.5); % Plot
                            % Right Half
                                theta2 = asin(1 ./ n .* sin(theta)); % Snell's Law to get outcoming angle
                                m2 = tan(theta2); % Slope
                                y2 = m2.*x + b; % Pass (1,0.5)
                                x = x + 1; % Shift the line to Right by 1
                                plot(app.Axes, x, y2,'Color',color); % Plot
                            % Axis
                                x_axis = linspace(0,2,10);
                                plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');
                        end

                    case 'Glass/Air Surface'
                        for i = 1:7
                            n = index(i);
                            color = colorList(i);
                            % Left Half
                                thetaC = asin(1./index(7));
                                if theta >= thetaC 
                                    theta = thetaC;
                                    app.CriticalAngleWarningEditField.Value = [num2str(rad2deg(thetaC)) ' degree'];
                                elseif theta <= -thetaC
                                    theta = -thetaC;
                                    app.CriticalAngleWarningEditField.Value = [num2str(rad2deg(-thetaC)) ' degree'];
                                end
                                
                                x = linspace(0,1,100);
                                m = tan(theta); % Slope
                                b = 0.5; % Center position
                                y = -m.*x + b; % Pass (1,0.5)
                                y = flip(y); % Plot from 1 to 0
                                plot(app.Axes, x, y,'Color','white','linewidth',1.5);
                            % Right Half
                                theta2 = asin(n ./ 1 .* sin(theta));
                                m2 = tan(theta2);
                                y2 = m2.*x + b; % Pass (1,0.5)
                                x = x + 1;
                                plot(app.Axes, x, y2,'Color',color);
                            % Axis
                                x_axis = linspace(0,2,10);
                                plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');
                        end

                    case 'Plane Parallel Plate'
                        for i = 1:7
                            n = index(i);
                            color = colorList(i);
                            % Left Half
                                x = linspace(0,0.8,100);
                                m = tan(theta); % Slope
                                b = 0.5; % Center position
                                y = -m.*x + b; % Pass (1,0.5)
                                y = flip(y); % Plot from 1 to 0
                                plot(app.Axes, x, y, "LineWidth", 1.5,'Color', 'white','linewidth',1.5);
                            % Middle
                                x2=linspace(0,0.4,100);
                                theta2=asin(1 ./ n .* sin(theta));
                                m2=tan(theta2);
                                b2=0.5;
                                y2=m2.*x2+b2;
                                plot(app.Axes, x2+0.8, y2, "LineWidth", 1.5,'Color',color);
                            % Right Half
                                x3=linspace(0,0.8,100);
                                theta3=asin(n./1.*sin(theta2));
                                m3=tan(theta3);
                                b3=y2(100);
                                y3=m3.*x3+b3;
                                plot(app.Axes, x3+1.2, y3, "LineWidth", 1.5,'Color',color);
                            % Axis
                                x_axis = linspace(0,2,10);
                                plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040')
                        end

                    case '60-Degree Prism'
                        for i = 1:7
                            n = index(i);
                            color = colorList(i);
                            thetain = pi/3;
                            theta4 = deg2rad(90);
                            theta3 = asin(1/index(7)*sin(theta4));
                            theta2 = deg2rad(60)-theta3;
                            theta1 = asin(index(7)*sin(theta2));
                            thetaC = theta1-deg2rad(30);

                            if index(7) >= 2
                                app.CriticalAngleWarningEditField.Value = 'no valid angle of incidence!';
                            elseif theta <= thetaC
                                theta = thetaC;
                                app.CriticalAngleWarningEditField.Value = [num2str(rad2deg(thetaC)) ' degree'];
                            elseif theta >= thetain
                                theta = thetain;
                                app.CriticalAngleWarningEditField.Value = 'maximum angle of incidence';
                            end

                            % Left Half
                                Llim = (0.5./tan(pi./3))+(1-1./sqrt(3));
                                x1 = linspace(0,Llim,100);
                                m1 = tan(theta); % Slope
                                b1 = 0.5;
                                y1 = -m1.*(x1) + b1;
                                y1 = flip(y1);
                                plot(app.Axes, x1, y1, "LineWidth", 1.5,'Color', 'white','linewidth',1.5);
                            % Middle
                                theta2 = asin((1./n).*sin(deg2rad(30) + theta)) - deg2rad(30);
                                
                                % Find Right limit
                                    m2 = tan(theta2);
                                    b2 = 0.5-Llim.*m2;
                                    mR = -tan(pi./3);
                                    bR = -(1 + 1./sqrt(3)).*mR;
                                    Rlim = abs((bR - b2)./(m2 - mR));
                                % Draw the line
                                    x2 = linspace(0,Rlim - Llim,100);
                                    m2 = tan(theta2);
                                    b2 = 0.5;
                                    y2 = m2.*x2 + b2;
                                    plot(app.Axes, x2 + Llim, y2, "LineWidth", 1.5,'Color',color);
                            % Right Half
                                x3 = linspace(0,2-Rlim,100);
                                theta3 = deg2rad(30) - asin((n./1).*sin(deg2rad(30) - theta2));
                                m3 = tan(theta3);
                                b3 = y2(100);
                                y3 = m3.*x3 + b3;
                                plot(app.Axes, x3 + Rlim, y3, "LineWidth", 1.5,'Color',color);
                        end

                    case 'Simple Positive Lens'
                        for i = 1:7
                            n = index(i);
                            color = colorList(i);
                            % Left half
                                Llim = 0.8;
                                x1 = linspace(0, Llim, 100);
                                m1 = tan(theta);
                                b1 = 0.5;
                                y1 = -m1 .* x1 + b1;
                                y1 = flip(y1);
                                plot(app.Axes, x1, y1, "LineWidth", 1.5,'Color','white','linewidth',1.5);
                            % Middle limit finding
                                % Get second angle (Snell)
                                theta2 = asin(1./n.*sin(theta));
    
                                % Get second angle (paraxial)
                                % theta2 = (1.*theta)./n;
                                
                                % Lens parameters
                                R = 2; % Radius of curvature
                                thickness = 0.2; % Thickness of the lens
                                x_center = 1; 
                                
                                % Ray equation parameters
                                m2 = tan(theta2);
                                bi = -(m2.*0.8);
    
                                % Find intercept
                                p = m2.^2+1;
                                q = 2.*m2.*bi - 2.*(-R+thickness+x_center);
                                r = bi.^2 + (-R+thickness+x_center).^2 - R.^2;
                                Rlim = (-q + sqrt(q.^2 - 4.*p.*r))./(2.*p);
                                
                            % Middle plot
                                x2 = linspace(Llim,Rlim,100);
                                m2 = tan(theta2);
                                b2 = bi + 0.5;
                                y2 = m2.*x2 + b2;
                                plot(app.Axes, x2, y2, "LineWidth", 1.5,'Color',color); 
                            % Right plot
                                x3 = linspace(Rlim,3,100);
    
                                % Find starting y value;
                                ylim = m2.*Rlim + b2;
    
                                % Find angle 3 (Snell)
                                thetan = atan((ylim - 0.5)./Rlim);
                                theta3 = asin(n.*sin(theta2 - thetan)) + thetan;
    
                                % Find angle 3 (Paraxial)
                                % power = (1 - n)./(-2);
                                % theta3 = n.*theta2 - (ylim - 0.5).*power;
    
                                % Equation for third ray
                                m3 = tan(theta3);
                                b3 = ylim - Rlim.*m3;
    
                                y3 = m3.*x3 +b3;
                                plot(app.Axes, x3, y3, "LineWidth", 1.5,'Color',color); 
                        end
                end

            else
                switch interface
                    % Monochromatic Light
                    case 'Air/Glass Surface'
                        % Left Half
                            x = linspace(0,1,100);
                            m = tan(theta); % Slope
                            b = 0.5; % Center position
                            y = -m.*x + b; % Pass (1,0.5)
                            y = flip(y); % Plot from 1 to 0
                            plot(app.Axes, x, y, "LineWidth", 1.5,'Color', color); % Plot
                        % Right Half
                            theta2 = asin(1 ./ n .* sin(theta)); % Snell's Law to get outcoming angle
                            m2 = tan(theta2); % Slope
                            y2 = m2.*x + b; % Pass (1,0.5)
                            x = x + 1; % Shift the line to Right by 1
                            plot(app.Axes, x, y2, "LineWidth", 1.5,'Color',color); % Plot
                        % Axis
                            x_axis = linspace(0,2,10);
                            plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');

                    case 'Glass/Air Surface'
                        % Left Half
                            thetaC = asin(1./n);

                            if theta >= thetaC 
                                theta = thetaC;
                                app.CriticalAngleWarningEditField.Value = [num2str(rad2deg(thetaC)) ' degree'];
                            elseif theta <= -thetaC
                                theta = -thetaC;
                                app.CriticalAngleWarningEditField.Value = [num2str(rad2deg(-thetaC)) ' degree'];
                            end

                            x = linspace(0,1,100);
                            m = tan(theta); % Slope
                            b = 0.5; % Center position
                            y = -m.*x + b; % Pass (1,0.5)
                            y = flip(y); % Plot from 1 to 0
                            plot(app.Axes, x, y,'Color',color);
                        % Right Half
                            theta2 = asin(n ./ 1 .* sin(theta));
                            m2 = tan(theta2);
                            y2 = m2.*x + b; % Pass (1,0.5)
                            x = x + 1;
                            plot(app.Axes, x, y2,'Color',color);
                        % Axis
                            x_axis = linspace(0,2,10);
                            plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040');

                    case 'Plane Parallel Plate'
                        % Left Half
                            x = linspace(0,0.8,100);
                            m = tan(theta); % Slope
                            b = 0.5; % Center position
                            y = -m.*x + b; % Pass (1,0.5)
                            y = flip(y); % Plot from 1 to 0
                            plot(app.Axes, x, y, "LineWidth", 1.5,'Color', color);
                        % Middle
                            x2 = linspace(0,0.4,100);
                            theta2 = asin(1 ./ n .* sin(theta));
                            m2 = tan(theta2);
                            b2 = 0.5;
                            y2 = m2.*x2 + b2;
                            plot(app.Axes, x2 + 0.8, y2, "LineWidth", 1.5,'Color',color);
                        % Right Half
                            x3 = linspace(0,0.8,100);
                            theta3 = asin(n./1.*sin(theta2));
                            m3 = tan(theta3);
                            b3 = y2(100);
                            y3 = m3.*x3+b3;
                            plot(app.Axes, x3 + 1.2, y3, "LineWidth", 1.5,'Color',color);
                        % Axis
                            x_axis = linspace(0,2,10);
                            plot(app.Axes, x_axis, 0.5 + 0.*x_axis, "--",'Color','#404040')    
                       
                    case '60-Degree Prism'
                        thetain = pi/3;
                        theta4 = deg2rad(90);
                        theta3 = asin(1/n*sin(theta4));
                        theta2 = deg2rad(60)-theta3;
                        theta1 = asin(n*sin(theta2));
                        thetaC = theta1-deg2rad(30);

                        if index(7) >= 2
                            app.CriticalAngleWarningEditField.Value = 'no valid angle of incidence!';
                        elseif theta <= thetaC
                            theta = thetaC;
                            app.CriticalAngleWarningEditField.Value = [num2str(rad2deg(thetaC)) ' degree'];
                        elseif theta >= thetain
                            theta = thetain;
                            app.CriticalAngleWarningEditField.Value = 'maximum angle of incidence';
                        end
                        
                        % Left Half
                            Llim = (0.5./tan(pi./3))+(1-1./sqrt(3));
                            x1 = linspace(0,Llim,100);
                            m1 = tan(theta); % Slope
                            b1 = 0.5;
                            y1 = -m1.*(x1) + b1;
                            y1 = flip(y1);
                            plot(app.Axes, x1, y1, "LineWidth", 1.5,'Color',color);
                        % Middle
                            theta2 = asin((1./n).*sin(deg2rad(30) + theta)) - deg2rad(30);
                            % Find Right limit
                                m2 = tan(theta2);
                                b2 = 0.5-Llim.*m2;
                                mR = -tan(pi./3);
                                bR = -(1 + 1./sqrt(3)).*mR;
                                Rlim = abs((bR - b2)./(m2 - mR));
                            % Draw the line
                                x2 = linspace(0,Rlim - Llim,100);
                                m2 = tan(theta2);
                                b2 = 0.5;
                                y2 = m2.*x2 + b2;
                                plot(app.Axes, x2 + Llim, y2, "LineWidth", 1.5,'Color',color);
                        % Right Half
                            x3 = linspace(0,2-Rlim,100);
                            theta3 = deg2rad(30) - asin((n./1).*sin(deg2rad(30) - theta2));
                            m3 = tan(theta3);
                            b3 = y2(100);
                            y3 = m3.*x3 + b3;
                            plot(app.Axes, x3 + Rlim, y3, "LineWidth", 1.5,'Color',color);

                    case 'Simple Positive Lens'
                        % Left half
                            Llim = 0.8;
                            x1 = linspace(0, Llim, 100);
                            m1 = tan(theta);
                            b1 = 0.5;
                            y1 = -m1 .* x1 + b1;
                            y1 = flip(y1);
                            plot(app.Axes, x1, y1, "LineWidth", 1.5,'Color',color);
                        % Middle limit finding
                            % Get second angle (Snell)
                            theta2 = asin(1./n.*sin(theta));

                            % Get second angle (paraxial)
                            % theta2 = (1.*theta)./n;
                            
                            % Lens parameters
                            R = 2; % Radius of curvature
                            thickness = 0.2; % Thickness of the lens
                            x_center = 1; 
                            
                            % Ray equation parameters
                            m2 = tan(theta2);
                            bi = -(m2.*0.8);

                            % Find intercept
                            p = m2.^2+1;
                            q = 2.*m2.*bi - 2.*(-R+thickness+x_center);
                            r = bi.^2 + (-R+thickness+x_center).^2 - R.^2;
                            Rlim = (-q + sqrt(q.^2 - 4.*p.*r))./(2.*p);
                            
                        % Middle plot
                            x2 = linspace(Llim,Rlim,100);
                            m2 = tan(theta2);
                            b2 = bi + 0.5;
                            y2 = m2.*x2 + b2;
                            plot(app.Axes, x2, y2, "LineWidth", 1.5,'Color',color); 
                        % Right plot
                            x3 = linspace(Rlim,3,100);

                            % Find starting y value;
                            ylim = m2.*Rlim + b2;

                            % Find angle 3 (Snell)
                            thetan = atan((ylim - 0.5)./Rlim);
                            theta3 = asin(n.*sin(theta2 - thetan)) + thetan;

                            % Find angle 3 (Paraxial)
                            % power = (1 - n)./(-2);
                            % theta3 = n.*theta2 - (ylim - 0.5).*power;

                            % Equation for third ray
                            m3 = tan(theta3);
                            b3 = ylim - Rlim.*m3;

                            y3 = m3.*x3 +b3;
                            plot(app.Axes, x3, y3, "LineWidth", 1.5,'Color',color);
                end
            end
            hold(app.Axes,"off")
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set axis equal
            axis(app.Axes, 'equal');
            xlim(app.Axes, [0 2]);
            ylim(app.Axes, [0 1]);

            % Initialize Axes to Air/Glass Surface
            medium2 = polyshape([1 1 2 2],[1 0 0 1]);
            plot(app.Axes, medium2,'FaceColor', 'cyan')

            % Initialize refractive/dispersion coefficients (BK7)
            global a B1 C1 B2 C2 B3 C3
            a = 0;
            B1 = 1.03961212;    C1 = 0.00600069867;
            B2 = 0.231792344;   C2 = 0.0200179144;
            B3 = 1.01046945;    C3 = 103.560653;

            % Initial Plot
            plotter(app)
        end

        % Value changed function: ElementsMenu
        function ElementsMenuValueChanged(app, event)
            % Update plot on value change
            plotter(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: SourceMenu
        function SourceMenuValueChanged(app, event)
            % Update plot on value change
            plotter(app)
        end

        % Value changed function: AngleBox
        function AngleBoxValueChanged(app, event)
            % Sync slider and box
            angle=app.AngleBox.Value;
            app.AngleSlider.Value=angle;

            % Update plot on value change
            plotter(app)
        end

        % Value changed function: AngleSlider
        function InputAngleValueChanged(app, event)
            % Sync slider and box
            angle = app.AngleSlider.Value;
            app.AngleBox.Value = angle;

            % Update plot on value change
            plotter(app)
        end

        % Value changed function: GlassMenu
        function GlassMenuValueChanged(app, event)
            glass = app.GlassMenu.Value;
            % Make variables global for index calculation
            global a B1 C1 B2 C2 B3 C3
            switch glass
                case 'N-BK7'
                    a = 0;
                    B1 = 1.03961212;    C1 = 0.00600069867;
                    B2 = 0.231792344;   C2 = 0.0200179144;                    
                    B3 = 1.01046945;    C3 = 103.560653;   

                case 'N-SF11'
                    a = 0;
                    B1 = 1.73759695;    C1 = 0.013188707;
                    B2 = 0.313747346;   C2 = 0.0623068142;  
                    B3 = 1.89878101;    C3 = 155.23629;

                case 'N-F2'
                    a = 0;
                    B1 = 1.39757037;    C1 = 0.00995906143;                    
                    B2 = 0.159201403;   C2 = 0.0546931752;                    
                    B3 = 1.2686543;     C3 = 119.248346;

                case 'Calcium Fluoride'
                    a = 0;
                    B1 = 0.56759;       C1 = 0.050264^2;
                    B2 = 0.47134;       C2 = 0.10026^2;
                    B3 = 3.8485;        C3 = 34.649^2;
                    
                case 'Zinc Selenide'
                    a = -0.689818;
                    B1 = 4.855169;      C1 = 0.056359;
                    B2 = 0.673922;      C2 = 0.056336;
                    B3 = 2.481890;      C3 = 2222.114;

                case 'Magnesium Fluoride'
                    a = 0.27620;
                    B1 = 0.60967;      C1 = 0.08636.^2;
                    B2 = 0.0080;      C2 = 18.0.^2;
                    B3 = 2.14973;      C3 = 25.0.^2;

                case 'Barium Fluoride'
                    a = 0.33973;
                    B1 = 0.81070;      C1 = 0.10065.^2;
                    B2 = 0.19652;      C2 = 29.87.^2;
                    B3 = 4.52469;      C3 = 53.82.^2; 
            end

            % Update plot on value change
            plotter(app)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Callback function
        function CriticalangleButtonPushed2(app, event)
            plotter(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Axes = uiaxes(app.UIFigure);
            app.Axes.CLim = [0 1];
            app.Axes.ALim = [0 1];
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Refraction & Dispersion Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OpticalElementsInterfacesDropDownLabel = uilabel(app.UIFigure);
            app.OpticalElementsInterfacesDropDownLabel.Text = 'Optical Elements/Interfaces';
            app.ElementsMenu = uidropdown(app.UIFigure);
            app.ElementsMenu.Items = {'Air/Glass Surface', 'Glass/Air Surface', 'Plane Parallel Plate', '60-Degree Prism', 'Simple Positive Lens'};
            app.ElementsMenu.ValueChangedFcn = createCallbackFcn(app, @ElementsMenuValueChanged, true);
            app.ElementsMenu.Value = 'Air/Glass Surface';
            app.StartingRayAngleSliderLabel = uilabel(app.UIFigure);
            app.StartingRayAngleSliderLabel.Text = 'Starting Ray Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [-90 90];
            app.AngleSlider.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.AngleSlider.MajorTickLabels = {'-90', '-80', '-70', '-60', '-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @InputAngleValueChanged, true);
            app.AngleSlider.MinorTicks = [-90 -88 -86 -84 -82 -80 -78 -76 -74 -72 -70 -68 -66 -64 -62 -60 -58 -56 -54 -52 -50 -48 -46 -44 -42 -40 -38 -36 -34 -32 -30 -28 -26 -24 -22 -20 -18 -16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90];
            app.OpticalGlassDropDownLabel = uilabel(app.UIFigure);
            app.OpticalGlassDropDownLabel.Text = 'Optical Glass';
            app.GlassMenu = uidropdown(app.UIFigure);
            app.GlassMenu.Items = {'N-BK7', 'N-SF11', 'N-F2', 'Calcium Fluoride', 'Zinc Selenide', 'Magnesium Fluoride', 'Barium Fluoride'};
            app.GlassMenu.ValueChangedFcn = createCallbackFcn(app, @GlassMenuValueChanged, true);
            app.GlassMenu.Value = 'N-BK7';
            app.AngleBox = uieditfield(app.UIFigure, 'numeric');
            app.AngleBox.ValueChangedFcn = createCallbackFcn(app, @AngleBoxValueChanged, true);
            app.SourceMenu = uidropdown(app.UIFigure);
            app.SourceMenu.Items = {'white light', 'red chromatic light', 'yellow chromatic light', 'green chromatic light', 'blue chromatic light'};
            app.SourceMenu.ValueChangedFcn = createCallbackFcn(app, @SourceMenuValueChanged, true);
            app.SourceMenu.Value = 'white light';
            app.IncidentLightSourceLabel = uilabel(app.UIFigure);
            app.IncidentLightSourceLabel.Text = 'Incident Light Source';
            app.CriticalAngleWarningEditFieldLabel = uilabel(app.UIFigure);
            app.CriticalAngleWarningEditFieldLabel.Text = 'Critical Angle Warning';
            app.CriticalAngleWarningEditField = uieditfield(app.UIFigure, 'text');
            app.CriticalAngleWarningEditField.Value = 'have not reached yet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffractionDispersionSimulator_finalversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end